classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        RESULTSPanel                    matlab.ui.container.Panel
        NeutralTerritoryEditField       matlab.ui.control.EditField
        NeutralTerritoryEditFieldLabel  matlab.ui.control.Label
        WhiteTerritoryEditField         matlab.ui.control.EditField
        WhiteTerritoryEditFieldLabel    matlab.ui.control.Label
        BlackTerritoryEditField         matlab.ui.control.EditField
        BlackTerritoryEditFieldLabel    matlab.ui.control.Label
        WhiteStonesEditField            matlab.ui.control.EditField
        WhiteStonesEditFieldLabel       matlab.ui.control.Label
        BlackStonesEditField            matlab.ui.control.EditField
        BlackStonesEditFieldLabel       matlab.ui.control.Label
        INPUTPanel                      matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        ImageAxes                       matlab.ui.control.UIAxes
        CONTROLSPanel                   matlab.ui.container.Panel
        RecalculateButton               matlab.ui.control.Button
        WhiteDetectionEditField         matlab.ui.control.NumericEditField
        WhiteDetectionEditFieldLabel    matlab.ui.control.Label
        BlackDetectionEditField         matlab.ui.control.NumericEditField
        BlackDetectionEditFieldLabel    matlab.ui.control.Label
        RmaxEditField                   matlab.ui.control.NumericEditField
        RmaxEditFieldLabel              matlab.ui.control.Label
        RminEditField                   matlab.ui.control.NumericEditField
        RminEditFieldLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        % save original image file in currentInput
        % save processed (grayscaled, etc) image in processedInput
        % save input image original file type in inputFileType
        % save processed edge image in currentEdge
        % save current processed output in currentOutput
        % save last saved mask size in maskSize
        currentInput = [];
        enhancedInput = [];
        currentOutput = [];
        inputFileType = [];
    end
    
    methods (Access = private)       
        function enhanceimage(app)
            shadow_lab = rgb2lab(app.currentInput);
            max_luminosity = 100;
            L = shadow_lab(:,:,1)/max_luminosity;
            %shadow_adapthisteq = shadow_lab;
            %shadow_adapthisteq(:,:,1) = adapthisteq(L)*max_luminosity;
            %app.enhancedInput = lab2rgb(shadow_adapthisteq);
            shadow_histeq = shadow_lab;
            shadow_histeq(:,:,1) = histeq(L)*max_luminosity;
            app.enhancedInput = lab2rgb(shadow_histeq);
        end

        function updateimage(app)
            im = app.currentInput;
            imagesc(app.ImageAxes, im);

            % Update automatically calculated radius
            boardSize = size(app.currentInput,1);
            Rmin = round(boardSize/19/4.5);
            Rmax = round((boardSize/19/2)*1.5);
            app.RminEditField.Value = Rmin;
            app.RmaxEditField.Value = Rmax;

            % Update output
            enhanceimage(app);
            updateoutput(app);
        end            
        
        function updateoutput(app)
            % Get all light and dark circles from image
            output = app.enhancedInput;
            Rmin = app.RminEditField.Value;
            Rmax = app.RmaxEditField.Value;
            [centersBright, radiiBright] = imfindcircles(output,[Rmin Rmax],'ObjectPolarity','bright','Sensitivity', app.WhiteDetectionEditField.Value);
            [centersDark, radiiDark] = imfindcircles(output,[Rmin Rmax],'ObjectPolarity','dark','Sensitivity', app.BlackDetectionEditField.Value);
            app.BlackStonesEditField.Value = int2str(length(centersDark));
            app.WhiteStonesEditField.Value = int2str(length(centersBright));
           
            % Display result
            imshow(app.currentInput,'Parent',app.ImageAxes);
            viscircles(app.ImageAxes, centersBright, radiiBright,'Color','b');
            viscircles(app.ImageAxes, centersDark, radiiDark,'LineStyle','--');
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            % Update the image
            app.currentInput = imread('default.png');
            updateimage(app);
        end

        % Callback function: LoadButton, OpenMenu
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.png','All Image Files'};
            fig = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [f, p] = uigetfile(filterspec);
            delete(fig);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               try
                   [~,~,ext] = fileparts(fname);
                   app.inputFileType = ext;
                   imsize = size(imread(fname));
                   if (length(imsize) == 2)
                       [indexedImage, map] = imread(fname);
                       rgbImage = ind2rgb(indexedImage, map);
                       app.currentInput = rgbImage;
                   else
                       im = imread(fname);
                       app.currentInput = im;
                   end
                   updateimage(app);

               catch ME
                   % If problem reading image, display error message
                   uialert(app.UIFigure, ME.message, 'Image Error');
                   return;
               end
               figure(app.UIFigure);
               
            end
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)
            updateoutput(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Go Score Counter';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.OpenMenu.Text = 'Open...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.CONTROLSPanel = uipanel(app.UIFigure);
            app.CONTROLSPanel.Title = 'CONTROLS';
            app.RminEditFieldLabel = uilabel(app.CONTROLSPanel);
            app.RminEditFieldLabel.Text = 'Rmin';
            app.RminEditField = uieditfield(app.CONTROLSPanel, 'numeric');
            app.RminEditField.Limits = [1 Inf];
            app.RminEditField.RoundFractionalValues = 'on';
            app.RminEditField.Value = 1;
            app.RmaxEditFieldLabel = uilabel(app.CONTROLSPanel);
            app.RmaxEditFieldLabel.Text = 'Rmax';
            app.RmaxEditField = uieditfield(app.CONTROLSPanel, 'numeric');
            app.RmaxEditField.Limits = [1 Inf];
            app.RmaxEditField.RoundFractionalValues = 'on';
            app.RmaxEditField.Value = 1;
            app.BlackDetectionEditFieldLabel = uilabel(app.CONTROLSPanel);
            app.BlackDetectionEditFieldLabel.Text = 'Black Detection';
            app.BlackDetectionEditField = uieditfield(app.CONTROLSPanel, 'numeric');
            app.BlackDetectionEditField.Limits = [0 1];
            app.BlackDetectionEditField.Value = 0.9;
            app.WhiteDetectionEditFieldLabel = uilabel(app.CONTROLSPanel);
            app.WhiteDetectionEditFieldLabel.Text = 'White Detection';
            app.WhiteDetectionEditField = uieditfield(app.CONTROLSPanel, 'numeric');
            app.WhiteDetectionEditField.Limits = [0 1];
            app.WhiteDetectionEditField.Value = 0.85;
            app.RecalculateButton = uibutton(app.CONTROLSPanel, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Text = 'Recalculate';
            app.INPUTPanel = uipanel(app.UIFigure);
            app.INPUTPanel.Title = 'INPUT';
            app.ImageAxes = uiaxes(app.INPUTPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.INPUTPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = 'RESULTS';
            app.BlackStonesEditFieldLabel = uilabel(app.RESULTSPanel);
            app.BlackStonesEditFieldLabel.Text = 'Black Stones';
            app.BlackStonesEditField = uieditfield(app.RESULTSPanel, 'text');
            app.BlackStonesEditField.Editable = 'off';
            app.WhiteStonesEditFieldLabel = uilabel(app.RESULTSPanel);
            app.WhiteStonesEditFieldLabel.Text = 'White Stones';
            app.WhiteStonesEditField = uieditfield(app.RESULTSPanel, 'text');
            app.WhiteStonesEditField.Editable = 'off';
            app.BlackTerritoryEditFieldLabel = uilabel(app.RESULTSPanel);
            app.BlackTerritoryEditFieldLabel.Text = 'Black Territory';
            app.BlackTerritoryEditField = uieditfield(app.RESULTSPanel, 'text');
            app.BlackTerritoryEditField.Editable = 'off';
            app.WhiteTerritoryEditFieldLabel = uilabel(app.RESULTSPanel);
            app.WhiteTerritoryEditFieldLabel.Text = 'White Territory';
            app.WhiteTerritoryEditField = uieditfield(app.RESULTSPanel, 'text');
            app.WhiteTerritoryEditField.Editable = 'off';
            app.NeutralTerritoryEditFieldLabel = uilabel(app.RESULTSPanel);
            app.NeutralTerritoryEditFieldLabel.Text = 'Neutral Territory';
            app.NeutralTerritoryEditField = uieditfield(app.RESULTSPanel, 'text');
            app.NeutralTerritoryEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end